classdef GUI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        CalibrationmethodparametersPanel  matlab.ui.container.Panel
        UseglassshapeforcalibrationCheckBox  matlab.ui.control.CheckBox
        HbCheckBox                   matlab.ui.control.CheckBox
        bCheckBox                    matlab.ui.control.CheckBox
        phi1CheckBox                 matlab.ui.control.CheckBox
        phi2CheckBox                 matlab.ui.control.CheckBox
        GlassheightLabel             matlab.ui.control.Label
        GlassheightSpinner           matlab.ui.control.Spinner
        HbSpinnerLabel               matlab.ui.control.Label
        HbSpinner                    matlab.ui.control.Spinner
        bSpinnerLabel                matlab.ui.control.Label
        bSpinner                     matlab.ui.control.Spinner
        phi1SpinnerLabel             matlab.ui.control.Label
        phi1Spinner                  matlab.ui.control.Spinner
        phi2SpinnerLabel             matlab.ui.control.Label
        phi2Spinner                  matlab.ui.control.Spinner
        GlassvolumemlSpinnerLabel    matlab.ui.control.Label
        GlassvolumemlSpinner         matlab.ui.control.Spinner
        Image                        matlab.ui.control.Image
        StatusLabel                  matlab.ui.control.Label
        StatusLabelLabel             matlab.ui.control.Label
        CalibrateButton              matlab.ui.control.Button
        DeletepointButton            matlab.ui.control.Button
        ReadpointsfromfileButton     matlab.ui.control.Button
        AddcalibrationpointButton    matlab.ui.control.Button
        CurrentvolumeEditField       matlab.ui.control.NumericEditField
        CurrentvolumeEditFieldLabel  matlab.ui.control.Label
        DatapointsListBox            matlab.ui.control.ListBox
        DatapointsListBoxLabel       matlab.ui.control.Label
        UIAxesCalib                  matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Gauge                        matlab.ui.control.LinearGauge
        Gauge_2                      matlab.ui.control.LinearGauge
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        updateSoundGraphTimer       timer
        lastFrequency               double 
        predictionV
        method2Calibrated
        recorder    
        calibrated
        emptyGlassFreq              double
        currentGlassVolume          double
    end
    
    methods (Access = private)
        
        function updateSoundGraph(app)
%            fs = 44100;
%            N_s = 4096 * 3;
%            r = audioDeviceReader(fs, N_s);
%            data = r();
%            release(r);

            % data = [1, 2, 3, 3];
            
            fs = 8000;
            data = app.recorder.getaudiodata();
            if length(data) > 10000
                data = data(end - 10000 + 1:end)';
            end
            
            % Restart the recorder if 60s of sound are already recorded
            if length(data) > fs * 60
                app.recorder.stop();
                app.recorder.record();
            end
            
            
            plot(app.UIAxes2, data);
            app.UIAxes2.YLim = [-1.5, 1.5];
            app.UIAxes2.XLim = [0, 10000];
            
            signal_fft = fft(data);
            freq = 0:(fs / length(data)):(fs / 2 - fs / length(data));
            signal_fft = signal_fft(1:length(data) / 2);
            
            % Cut the data to better see the difference in frequencies.
            % We know that the glass frequency is usually below 1000 Hzf
            freq = freq(1:2000);
            signal_fft = signal_fft(1:2000);
            
            plot(app.UIAxes, freq, 2 * abs(signal_fft));
                
            app.UIAxes.XLim = [0, fs * (length(freq) / length(data))];
            app.UIAxes.YLim = [-1, 500];
            

            [~, argmax] = max(abs(signal_fft)');
            app.lastFrequency = freq(argmax);
            
            if app.calibrated
                [dom_freq, m1, m2] = predict(app.predictionV, freq, abs(signal_fft)');
                app.lastFrequency = dom_freq;
                if m1 > -10 && m1 < 600
                    
                    app.Gauge.Value = m1;
                    app.Gauge_2.Value = m2;
                end
               
            end
        end
        
        
        function calibrate(app, calibration_vol, calibration_freq)
            glass_volume = app.GlassvolumemlSpinner.Value;
            
            if glass_volume == 0
                app.StatusLabel.Text = "Glass volume may not be 0...";
                app.StatusLabel.FontColor = [0.7, 0.2, 0.2];
                return;
            end
            
            use_method_2 = app.UseglassshapeforcalibrationCheckBox.Value;
            if use_method_2
                app.method2Calibrated = true;
                glass_height = app.GlassheightSpinner.Value;
                defaults = [nan, nan, nan, nan];
                
                if app.bCheckBox.Value
                    defaults(1) = app.bSpinner.Value;
                end
                
                if app.HbCheckBox.Value
                    defaults(2) = app.HbSpinner.Value;
                end
                
                if app.phi1CheckBox.Value
                    defaults(3) = app.phi1Spinner.Value;
                end
                
                if app.phi2CheckBox.Value
                    defaults(4) = app.phi2Spinner.Value;
                end
            else
                app.method2Calibrated = false;
                glass_height = 0;
                defaults = [0, 0, 0, 0];
            end
            
            app.predictionV = calibrate_from_measurements(calibration_freq, calibration_vol, glass_volume, glass_height, defaults);
            app.Gauge.Limits = [0, glass_volume];
            app.Gauge_2.Limits = [0, glass_volume];
            
            % Take the 0 volume frequency
            plot_freq = max(calibration_freq);
            mls1_pred = [];
            mls2_pred = [];
            for freq = plot_freq:-1:0
               [~, m1, m2] =  predict(app.predictionV, freq, 1);
               
               if m1 > glass_volume && (~use_method_2 || m2 > glass_volume)
                   break;
               end
               min_plot_freq = freq;
               mls1_pred = [mls1_pred, m1];
               mls2_pred = [mls2_pred, m2];
                
            end

            x = min_plot_freq:plot_freq;
            scatter(app.UIAxesCalib, calibration_vol, calibration_freq);
            hold(app.UIAxesCalib, 'on');
            plot(app.UIAxesCalib, fliplr(mls1_pred), x, 'r');
            if use_method_2
                plot(app.UIAxesCalib, fliplr(mls2_pred), x, 'b');
            end
            hold(app.UIAxesCalib, 'off');
            axis(app.UIAxesCalib, 'tight');
     
            
            app.calibrated = true;
            
            app.StatusLabel.Text = "Calibration was successfull...";
            app.StatusLabel.FontColor = [0.2, 0.7, 0.2];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup audioreader
            % app.audioReader = audioDeviceReader(44100, 1024);

            app.recorder = audiorecorder(8000, 24, 1);
            app.recorder.record();
            
            app.calibrated = false;
            app.DatapointsListBox.Items = "Volume, frequency";
            
            % Setup plot callback timer
            app.updateSoundGraphTimer = timer('StartDelay', 3, 'Period', 75e-3, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
            app.updateSoundGraphTimer.TimerFcn = @(obj,event) updateSoundGraph(app);
            app.updateSoundGraphTimer.start;

        end

        % Button pushed function: DeletepointButton
        function DeletepointButtonPushed(app, event)
            [~,idx] = ismember(app.DatapointsListBox.Value, app.DatapointsListBox.Items);
            if (idx ~= 1)
                app.DatapointsListBox.Items(idx) = [];
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.recorder.stop();
            stop(app.updateSoundGraphTimer);
            delete(app)
            
        end

        % Button pushed function: AddcalibrationpointButton
        function AddcalibrationpointButtonPushed(app, event)
            volume = app.CurrentvolumeEditField.Value;
            freq = app.lastFrequency;
            items = string(app.DatapointsListBox.Items);
            items(end + 1) = "" + volume + ", " + freq;
            
            app.DatapointsListBox.Items = items;
            
            
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            calibration_items = string(app.DatapointsListBox.Items);
            calibration_items = calibration_items(2:end);
            if length(calibration_items) < 2
                app.StatusLabel.Text = "Calibration error: too few points";
                app.StatusLabel.FontColor = [1, 0, 0];
                return
            end
            
            pat = lettersPattern | " " | ",";
            calibration_v = zeros([1, length(calibration_items)]);
            calibration_freq = calibration_v;
            
            for i = 1:length(calibration_items)
                n = split(calibration_items(i), pat);
                n = n(strlength(n) > 0);
                n = double(n);
                
                calibration_v(i) = n(1);
                calibration_freq(i) = n(2);
            end
            
            if (~any(calibration_v == 0))
                app.StatusLabel.Text = "Zero volume is not first in the data...";
                app.StatusLabel.FontColor = [1, 0, 0];
                return;
            end
            app.emptyGlassFreq = calibration_freq(1);
            
            app.calibrate(calibration_v, calibration_freq);
            
            
        end

        % Button pushed function: ReadpointsfromfileButton
        function ReadpointsfromfileButtonPushed(app, event)
            [file, path] = uigetfile;
            if (~file)
                return;
            end
            calib_data = csvread([path file]);
            
            if (calib_data(1, 1) ~= 0)
                app.StatusLabel.Text = "No zero volume frequency in calibration data...";
                app.StatusLabel.FontColor = [1, 0, 0];
                return;
            end
            app.emptyGlassFreq = calib_data(1, 2);
            
            app.calibrate(calib_data(:, 1)', calib_data(:, 2)');
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {872, 872};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {375, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.CalibrationmethodparametersPanel = uipanel(app.GridLayout3);
            app.CalibrationmethodparametersPanel.Title = 'Calibration method parameters';
            app.UseglassshapeforcalibrationCheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.UseglassshapeforcalibrationCheckBox.Text = 'Use glass shape for calibration';
            app.HbCheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.HbCheckBox.Text = '';
            app.bCheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.bCheckBox.Text = '';
            app.phi1CheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.phi1CheckBox.Text = '';
            app.phi2CheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.phi2CheckBox.Text = '';
            app.GlassheightLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.GlassheightLabel.Text = 'Glass height ';
            app.GlassheightSpinner = uispinner(app.CalibrationmethodparametersPanel);
            app.HbSpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.HbSpinnerLabel.Text = 'Hb';
            app.HbSpinner = uispinner(app.CalibrationmethodparametersPanel);
            app.bSpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.bSpinnerLabel.Text = 'b';
            app.bSpinner = uispinner(app.CalibrationmethodparametersPanel);
            app.phi1SpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.phi1SpinnerLabel.Text = 'phi1';
            app.phi1Spinner = uispinner(app.CalibrationmethodparametersPanel);
            app.phi2SpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.phi2SpinnerLabel.Text = 'phi2';
            app.phi2Spinner = uispinner(app.CalibrationmethodparametersPanel);
            app.GlassvolumemlSpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.GlassvolumemlSpinnerLabel.Text = 'Glass volume [ml]';
            app.GlassvolumemlSpinner = uispinner(app.CalibrationmethodparametersPanel);
            app.GlassvolumemlSpinner.Step = 10;
            app.GlassvolumemlSpinner.Limits = [0 Inf];
            app.Image = uiimage(app.GridLayout3);
            app.Image.ImageSource = 'glass.png';
            app.StatusLabel = uilabel(app.GridLayout3);
            app.StatusLabel.Text = {''; ''; ''};
            app.StatusLabelLabel = uilabel(app.GridLayout3);
            app.StatusLabelLabel.Text = 'Status: ';
            app.CalibrateButton = uibutton(app.GridLayout3, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.DeletepointButton = uibutton(app.GridLayout3, 'push');
            app.DeletepointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletepointButtonPushed, true);
            app.DeletepointButton.Text = 'Delete point';
            app.ReadpointsfromfileButton = uibutton(app.GridLayout3, 'push');
            app.ReadpointsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadpointsfromfileButtonPushed, true);
            app.ReadpointsfromfileButton.Text = 'Read points from file';
            app.AddcalibrationpointButton = uibutton(app.GridLayout3, 'push');
            app.AddcalibrationpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddcalibrationpointButtonPushed, true);
            app.AddcalibrationpointButton.Text = 'Add calibration point';
            app.CurrentvolumeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.CurrentvolumeEditFieldLabel = uilabel(app.GridLayout3);
            app.CurrentvolumeEditFieldLabel.Text = 'Current volume';
            app.DatapointsListBox = uilistbox(app.GridLayout3);
            app.DatapointsListBox.Items = {};
            app.DatapointsListBox.Value = {};
            app.DatapointsListBoxLabel = uilabel(app.GridLayout3);
            app.DatapointsListBoxLabel.Text = 'Data points';
            app.UIAxesCalib = uiaxes(app.GridLayout3);
            title(app.UIAxesCalib, 'Calibration result')
            xlabel(app.UIAxesCalib, 'Volume [ml]')
            ylabel(app.UIAxesCalib, 'Frequency [Hz]')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Gauge = uigauge(app.GridLayout2, 'linear');
            app.Gauge.Value = 5;
            app.Gauge_2 = uigauge(app.GridLayout2, 'linear');
            app.Gauge_2.Value = 5;
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Frequency')
            xlabel(app.UIAxes, 'Frequency [Hz]')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, {'Signal in time'; ''})
            xlabel(app.UIAxes2, 'Samples')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end