classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        CurrentvolumeEditFieldLabel  matlab.ui.control.Label
        CurrentvolumeEditField       matlab.ui.control.NumericEditField
        AddcalibrationpointButton    matlab.ui.control.Button
        ReadpointsfromfileButton     matlab.ui.control.Button
        DatapointsListBoxLabel       matlab.ui.control.Label
        DatapointsListBox            matlab.ui.control.ListBox
        DeletepointButton            matlab.ui.control.Button
        CalibrateButton              matlab.ui.control.Button
        StatusLabelLabel             matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        CalibrationmethodparametersPanel  matlab.ui.container.Panel
        UseglassshapeforcalibrationCheckBox  matlab.ui.control.CheckBox
        HbCheckBox                   matlab.ui.control.CheckBox
        bCheckBox                    matlab.ui.control.CheckBox
        phi1CheckBox                 matlab.ui.control.CheckBox
        phi2CheckBox                 matlab.ui.control.CheckBox
        GlassheightLabel             matlab.ui.control.Label
        GlassheightSpinner           matlab.ui.control.Spinner
        HbSpinnerLabel               matlab.ui.control.Label
        HbSpinner                    matlab.ui.control.Spinner
        bSpinnerLabel                matlab.ui.control.Label
        bSpinner                     matlab.ui.control.Spinner
        phi1SpinnerLabel             matlab.ui.control.Label
        phi1Spinner                  matlab.ui.control.Spinner
        phi2SpinnerLabel             matlab.ui.control.Label
        phi2Spinner                  matlab.ui.control.Spinner
        GlassvolumemlSpinnerLabel    matlab.ui.control.Label
        GlassvolumemlSpinner         matlab.ui.control.Spinner
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GaugeLabel                   matlab.ui.control.Label
        Gauge                        matlab.ui.control.LinearGauge
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        updateSoundGraphTimer       timer
        lastFrequency               double 
        predictionV
        emptyGlassFreq              double
        currentGlassVolume          double
    end
    
    methods (Access = private)
        
        function updateSoundGraph(app)
            fs = 44100;
            N_s = 4096 * 3;
            r = audioDeviceReader(fs, N_s);
            data = r();
            release(r);
            % data = [1, 2, 3, 3];
            plot(app.UIAxes2, data(1:N_s));
            app.UIAxes2.YLim = [-1.5, 1.5];
            app.UIAxes2.XLim = [0, N_s];
            
            signal_fft = fft(data);
            freq = 0:(fs / N_s):(fs / 2 - fs / N_s);
            signal_fft = signal_fft(1:N_s / 2);
            
            % Cut the data to better see the difference in frequencies.
            % We know that the glass frequency is usually below 1000 Hzf
            freq = freq(1:300);
            signal_fft = signal_fft(1:300);
            
            plot(app.UIAxes, freq, 2 * abs(signal_fft));
                
            app.UIAxes.XLim = [0, fs * (length(freq) / N_s)];
            app.UIAxes.YLim = [-1, 500];
            
            if app.emptyGlassFreq ~= 0
                [dom_freq, d] = predict([app.predictionH, app.predictionA], freq, app.emptyGlassFreq, abs(signal_fft)');
                volume = app.currentGlassVolume - d;
                if volume < app.currentGlassVolume && volume > 0
                    disp("Volume");
                    disp(volume);
                    app.Gauge.Value = volume;
                    app.Gauge.Limits = [0, app.currentGlassVolume];
                end
                app.lastFrequency = dom_freq;
            end
        end
        
        
        function calibrate(app, calibration_vol, calibration_freq)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup audioreader
            % app.audioReader = audioDeviceReader(44100, 1024);


            app.DatapointsListBox.Items = "Volume, frequency";
            
            % Setup plot callback timer
            app.updateSoundGraphTimer = timer('StartDelay', 3, 'Period', 1000e-3, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
            app.updateSoundGraphTimer.TimerFcn = @(obj,event) updateSoundGraph(app);
            app.updateSoundGraphTimer.start;

        end

        % Button pushed function: DeletepointButton
        function DeletepointButtonPushed(app, event)
            [~,idx] = ismember(app.DatapointsListBox.Value, app.DatapointsListBox.Items);
            if (idx ~= 1)
                app.DatapointsListBox.Items(idx) = [];
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.updateSoundGraphTimer);
            delete(app)
            
        end

        % Button pushed function: AddcalibrationpointButton
        function AddcalibrationpointButtonPushed(app, event)
            volume = app.CurrentvolumeEditField.Value;
            freq = app.lastFrequency;
            items = string(app.DatapointsListBox.Items);
            items(end + 1) = "" + volume + ", " + freq;
            
            app.DatapointsListBox.Items = items;
            
            
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            calibration_items = string(app.DatapointsListBox.Items);
            calibration_items = calibration_items(2:end);
            if length(calibration_items) < 2
                app.StatusLabel.Text = "Calibration error: too few points";
                app.StatusLabel.FontColor = [1, 0, 0];
                return
            end
            
            pat = lettersPattern | " " | ",";
            calibration_v = zeros([1, length(calibration_items)]);
            calibration_freq = calibration_v;
            
            for i = 1:length(calibration_items)
                n = split(calibration_items(i), pat);
                n = n(strlength(n) > 0);
                n = double(n);
                
                calibration_v(i) = n(1);
                calibtation_freq(i) = n(2);
            end
            
            if (calibration_v(1) ~= 0)
                app.StatusLabel.Text = "Zero volume is not first in the data...";
                app.StatusLabel.FontColor = [1, 0, 0];
                return;
            end
            app.emptyGlassFreq = calibration_freq(1);
            
            calib_res = calibrate_from_measurements(calibration_freq, calibration_v, app.GlassvolumemlSpinner.Value);
            app.predictionH = calib_res(1);
            app.predictionA = calib_res(2);
            
            app.StatusLabel.Text = "Calibration was successfull...";
            app.StatusLabel.FontColor = [0, 1, 0];
            
            app.currentGlassVolume = app.GlassvolumemlSpinner.Value;
            
            
        end

        % Button pushed function: ReadpointsfromfileButton
        function ReadpointsfromfileButtonPushed(app, event)
            [file, path] = uigetfile;
            calib_data = csvread([path file]);
            
            if (calib_data(1, 1) ~= 0)
                app.StatusLabel.Text = "No zero volume frequency in calibratino data...";
                app.StatusLabel.FontColor = [1, 0, 0];
                return;
            end
            app.emptyGlassFreq = calib_data(1, 2);
            
            calib_res = calibrate_from_measurements(calib_data(:, 2)', calib_data(:, 1)', app.GlassvolumemlSpinner.Value);
            app.predictionH = calib_res(1);
            app.predictionA = calib_res(2);
            
            app.StatusLabel.Text = "Calibration was successfull...";
            app.StatusLabel.FontColor = [0, 1, 0];
            app.currentGlassVolume = app.GlassvolumemlSpinner.Value;
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {872, 872};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {375, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CurrentvolumeEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentvolumeEditFieldLabel.Text = 'Current volume';
            app.CurrentvolumeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AddcalibrationpointButton = uibutton(app.LeftPanel, 'push');
            app.AddcalibrationpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddcalibrationpointButtonPushed, true);
            app.AddcalibrationpointButton.Text = 'Add calibration point';
            app.ReadpointsfromfileButton = uibutton(app.LeftPanel, 'push');
            app.ReadpointsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadpointsfromfileButtonPushed, true);
            app.ReadpointsfromfileButton.Text = 'Read points from file';
            app.DatapointsListBoxLabel = uilabel(app.LeftPanel);
            app.DatapointsListBoxLabel.Text = 'Data points';
            app.DatapointsListBox = uilistbox(app.LeftPanel);
            app.DatapointsListBox.Items = {};
            app.DatapointsListBox.Value = {};
            app.DeletepointButton = uibutton(app.LeftPanel, 'push');
            app.DeletepointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletepointButtonPushed, true);
            app.DeletepointButton.Text = 'Delete point';
            app.CalibrateButton = uibutton(app.LeftPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.StatusLabelLabel = uilabel(app.LeftPanel);
            app.StatusLabelLabel.Text = 'Status: ';
            app.StatusLabel = uilabel(app.LeftPanel);
            app.StatusLabel.Text = {''; ''; ''};
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'glass.png';
            app.CalibrationmethodparametersPanel = uipanel(app.LeftPanel);
            app.CalibrationmethodparametersPanel.Title = 'Calibration method parameters';
            app.UseglassshapeforcalibrationCheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.UseglassshapeforcalibrationCheckBox.Text = 'Use glass shape for calibration';
            app.HbCheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.HbCheckBox.Text = '';
            app.bCheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.bCheckBox.Text = '';
            app.phi1CheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.phi1CheckBox.Text = '';
            app.phi2CheckBox = uicheckbox(app.CalibrationmethodparametersPanel);
            app.phi2CheckBox.Text = '';
            app.GlassheightLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.GlassheightLabel.Text = 'Glass height ';
            app.GlassheightSpinner = uispinner(app.CalibrationmethodparametersPanel);
            app.HbSpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.HbSpinnerLabel.Text = 'Hb';
            app.HbSpinner = uispinner(app.CalibrationmethodparametersPanel);
            app.bSpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.bSpinnerLabel.Text = 'b';
            app.bSpinner = uispinner(app.CalibrationmethodparametersPanel);
            app.phi1SpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.phi1SpinnerLabel.Text = 'phi1';
            app.phi1Spinner = uispinner(app.CalibrationmethodparametersPanel);
            app.phi2SpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.phi2SpinnerLabel.Text = 'phi2';
            app.phi2Spinner = uispinner(app.CalibrationmethodparametersPanel);
            app.GlassvolumemlSpinnerLabel = uilabel(app.CalibrationmethodparametersPanel);
            app.GlassvolumemlSpinnerLabel.Text = 'Glass volume [ml]';
            app.GlassvolumemlSpinner = uispinner(app.CalibrationmethodparametersPanel);
            app.GlassvolumemlSpinner.Step = 10;
            app.GlassvolumemlSpinner.Limits = [0 Inf];
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GaugeLabel = uilabel(app.GridLayout2);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.GridLayout2, 'linear');
            app.Gauge.Value = 5;
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end